classdef exp_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ConnectButton            matlab.ui.control.StateButton
        StartButton              matlab.ui.control.StateButton
        ChooseprotocolButton     matlab.ui.control.StateButton
        MousenameEditField       matlab.ui.control.EditField
        MousenameEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        protocol % Description
        mouse_name
        server_stim = tcpserver("0.0.0.0",plab.locations.bonsai_port);
        server_timelite = tcpserver("0.0.0.0",plab.locations.timelite_port);
        server_mousecam = tcpserver("0.0.0.0",plab.locations.mousecam_port);
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChooseprotocolButton
        function ChooseprotocolButtonValueChanged(app, event)
            value = app.ChooseprotocolButton.Value;
            app.protocol = uigetfile('C:\Users\amarica\Documents\GitHub\PetersLab_code\Bonsai stuff');
            figure(app.UIFigure);
            app.ChooseprotocolButton.Text = ['Chosen ' app.protocol];
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value == 1
                s = struct('mouse', app.mouse_name, 'protocol', app.protocol);
                encode_s = jsonencode(s);
                % write(server_stim, encode_s,'string');
                waitfor(app.server_stim, 'Connected', 1);
                write(app.server_stim,encode_s,'string');
            else
                app.StartButton.Text = 'Start';
            end

        end

        % Value changed function: MousenameEditField
        function MousenameEditFieldValueChanged(app, event)
            app.mouse_name =  app.MousenameEditField.Value;
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            initialize(app);
            app.ConnectButton.Text = 'Connected';
            app.ConnectButton.BackgroundColor = 'g';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MousenameEditFieldLabel = uilabel(app.UIFigure);
            app.MousenameEditFieldLabel.Text = 'Mouse name';
            app.MousenameEditField = uieditfield(app.UIFigure, 'text');
            app.MousenameEditField.ValueChangedFcn = createCallbackFcn(app, @MousenameEditFieldValueChanged, true);
            app.ChooseprotocolButton = uibutton(app.UIFigure, 'state');
            app.ChooseprotocolButton.ValueChangedFcn = createCallbackFcn(app, @ChooseprotocolButtonValueChanged, true);
            app.ChooseprotocolButton.Text = 'Choose protocol';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end